classdef exploreSimulations < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
    end


    properties (Access = public)
        TileLayout matlab.graphics.layout.TiledChartLayout % Added property for TileLayout
        SecondLayout matlab.graphics.layout.TiledChartLayout % Added property for TileLayout
        ThirdLayout matlab.graphics.layout.TiledChartLayout
        t
        RightPanel matlab.ui.container.Panel
        PlotTitle matlab.ui.control.Label  % Label used to display the overall title above the plot panel
        TimeSlider matlab.ui.control.Slider  % New slider above SecondLayout
        TimeSliderLabel matlab.ui.control.Label
        DepthSlider matlab.ui.control.Slider  % New slider above SecondLayout
        DeptheSliderLabel matlab.ui.control.Label
        Button1
        Button2
        Button3
        PlotSelector matlab.ui.control.DropDown % Drop-down menu to select plot type
        Panel1 matlab.ui.container.Panel
        Panel2 matlab.ui.container.Panel
        Panel3 matlab.ui.container.Panel
        LastValidPlotType
    end


    methods (Access = private)
        %% Callback function to do when TimeSlider value change
        function updateTimeSliderSliderValue(app, slider, sim)
            timeValue = floor(app.TimeSlider.Value);

            % Update the label with current time
            app.TimeSliderLabel.Text = sprintf('Time of Day: %d', timeValue);

            Bdepth = sum(sim.B(timeValue,:,:),3);
            iDepth = find(Bdepth == max(Bdepth));

            if isempty(app.SecondLayout) || ~isvalid(app.SecondLayout)
                return;
            end

            delete(allchild(app.SecondLayout)); % Clear the plots

            % Determine the plot type to use: current if valid, else fallback
            if ismember(app.PlotSelector.Value, sim.p.nameGroup)
                plotType = app.PlotSelector.Value;
                app.LastValidPlotType = plotType;  % Optional: refresh memory
            else
                plotType = app.LastValidPlotType;
            end

            plotWatercolumntest(sim, timeValue, [] ,[], plotType, app.SecondLayout, 'depthMax', 200);
        end

        %% Callback function to do when DepthSlider value change
        function onDepthSliderChanged(app, slider, sim)
            % Round the value to nearest valid index
            idx = round(slider.Value);

            % Clamp to valid range just in case
            idx = max(1, min(length(sim.z), idx));

            % Force slider to jump to valid value
            slider.Value = idx;

            % Update label
            app.DeptheSliderLabel.Text = sprintf('Depth: %.0f m', -sim.z(idx));


            % Update the plot
            if isempty(app.ThirdLayout) || ~isvalid(app.ThirdLayout)
                return;
            end

            delete(allchild(app.ThirdLayout));
            plotSizeSpectrumTest(sim, floor(app.TimeSlider.Value), idx, [], [], app.ThirdLayout);
        end


        %% Resize function
        function onResize(app)
            figPos = app.UIFigure.Position;
            figWidth = figPos(3);
            figHeight = figPos(4);

            margin = 10;
            numPanels = 3;
            rightPadding = 20;  % space between last panel and right edge
            leftPadding = 10;   % space before first panel

            % Calculate available width for panels (minus all margins and paddings)
            totalMargins = margin * (numPanels - 1);
            availableWidth = figWidth - leftPadding - rightPadding - totalMargins;
            tileW = availableWidth / numPanels;

            % Panel horizontal positions
            tileX1 = leftPadding;
            tileX2 = tileX1 + tileW + margin;
            tileX3 = tileX2 + tileW + margin;

            % Panel vertical size and normalized positions
            tileY = 0.1 * figHeight;
            tileH = 0.7 * figHeight;
            normalizedH = (tileH) / figHeight;
            normalizedW = (tileW) / figWidth;

            % Assign positions to panels (normalized)
            app.Panel1.Position = [tileX1 / figWidth, tileY / figHeight, normalizedW, normalizedH];
            app.Panel2.Position = [tileX2 / figWidth, tileY / figHeight, normalizedW, normalizedH];
            app.Panel3.Position = [tileX3 / figWidth, tileY / figHeight, normalizedW, normalizedH];

            % Update PlotSelector position
            if ~isempty(app.PlotSelector) && isvalid(app.PlotSelector)
                app.PlotSelector.Position = [tileX1, figHeight - 50, 150, 22];
            end
        end
        %% Update the plots when the PlotSelector value change.
        function updatePlot(app, event, sim)
            plotType = app.PlotSelector.Value;

            % If valid, update LastValidPlotType
            if ismember(plotType, sim.p.nameGroup)
                app.LastValidPlotType = plotType;
            end

            % Try plotting
            if ~isempty(app.TileLayout) && isvalid(app.TileLayout)
                plotWatercolumnTimeTest(sim, 'depthMax', 200, plotType, app.TileLayout);
            end
            if ~isempty(app.SecondLayout) && isvalid(app.SecondLayout) && ismember(plotType, sim.p.nameGroup)
                plotWatercolumntest(sim, floor(app.TimeSlider.Value), [], [], plotType, app.SecondLayout, 'depthMax', 200);
            end

            % Try resizing
            onResize(app);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, sim)
            app.UIFigure.AutoResizeChildren = 'off';
            app.UIFigure.SizeChangedFcn = @(src, event) onResize(app);

            % Window size setup
            figPos = app.UIFigure.Position;
            figWidth = figPos(3);
            figHeight = figPos(4);

            % Set up the plot selector drop-down menu
            app.PlotSelector = uidropdown(app.UIFigure);
            app.PlotSelector.Items = [sim.p.nameGroup, sim.p.nameNutrientsLong];
            app.PlotSelector.ValueChangedFcn = @(src, event) updatePlot(app, event, sim);

            %% --- First Panel and Layout (unchanged) ---
            app.Panel1 = uipanel(app.UIFigure, 'Units', 'normalized');
            app.TileLayout = tiledlayout(app.Panel1, 1, 1);
            plotWatercolumnTimeTest(sim, 'depthMax', 200, app.PlotSelector.Value, app.TileLayout);

            %% --- Second Panel and Layout (Time Slider) ---
            app.Panel2 = uipanel(app.UIFigure, 'Units', 'normalized');
            panel2Grid = uigridlayout(app.Panel2, [3, 1]);
            panel2Grid.RowHeight = {20, 40, '1x'};

            % Time label first
            app.TimeSliderLabel = uilabel(panel2Grid);
            app.TimeSliderLabel.Layout.Row = 1;
            app.TimeSliderLabel.HorizontalAlignment = 'center';
            

            % Then time slider
            app.TimeSlider = uislider(panel2Grid);
            app.TimeSlider.Layout.Row = 2;
            app.TimeSlider.Limits = [1, length(sim.t)];
            app.TimeSlider.MajorTicks = [1, 365, 730, 1095, 1460, 1825];
            app.TimeSlider.Value = 180;
            app.TimeSlider.ValueChangedFcn = @(src, event) updateTimeSliderSliderValue(app, src, sim);
            app.TimeSliderLabel.Text = sprintf('Time of Day: %d', app.TimeSlider.Value);
            plotPanel = uipanel(panel2Grid);
            plotPanel.Layout.Row = 3;
            tiled = tiledlayout(plotPanel, 2, 1, 'TileSpacing', 'compact', 'Padding', 'compact');
            app.SecondLayout = tiled;
            plotWatercolumntest(sim, floor(app.TimeSlider.Value), [], [], app.PlotSelector.Value, app.SecondLayout, 'depthMax', 200);

            %% --- Third Panel and Layout (Depth Slider) ---
            app.Panel3 = uipanel(app.UIFigure, 'Units', 'normalized');
            panel3Grid = uigridlayout(app.Panel3, [3, 1]);
            panel3Grid.RowHeight = {20, 40, '1x'};

            % Depth label first
            app.DeptheSliderLabel = uilabel(panel3Grid);
            app.DeptheSliderLabel.Layout.Row = 1;
            app.DeptheSliderLabel.HorizontalAlignment = 'center';
            
            
            % Then depth slider (limit to max 400 m)
            maxDepthAllowed = 435;
            validDepthIndices = find(sim.z <= maxDepthAllowed);  % indices where depth ≤ 400m
            maxIdx = max(validDepthIndices);
            
            roundedDepths = round(-sim.z(validDepthIndices));  % Round and flip sign
            disp(maxIdx)
            app.DepthSlider = uislider(panel3Grid);
            app.DepthSlider.Layout.Row = 2;
            app.DepthSlider.Limits = [1, maxIdx];
            app.DepthSlider.MajorTicks = 1:maxIdx;
            app.DepthSlider.MajorTickLabels = string(roundedDepths);
            app.DepthSlider.Value = min(7, maxIdx);  % default to 7 or max available
            app.DepthSlider.ValueChangedFcn = @(src, event) onDepthSliderChanged(app, src, sim);
            app.DeptheSliderLabel.Text = sprintf('Depth: %.0f m', -sim.z(app.DepthSlider.Value));

            % Plot panel below slider
            Panel3PlotPanel = uipanel(panel3Grid);
            Panel3PlotPanel.Layout.Row = 3;
            tiled3 = tiledlayout(Panel3PlotPanel, 4, 1, 'TileSpacing', 'compact', 'Padding', 'compact');
            app.ThirdLayout = tiled3;

            % Initial plot for depth
            plotSizeSpectrumTest(sim, floor(app.TimeSlider.Value), app.DepthSlider.Value, [], [], app.ThirdLayout);

            % Store initial plot type
            if ismember(app.PlotSelector.Value, sim.p.nameGroup)
                app.LastValidPlotType = app.PlotSelector.Value;
            else
                app.LastValidPlotType = sim.p.nameGroup{1};
            end

            % Resize initial layout
            onResize(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exploreSimulations(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end